classdef IQ_Bias_Control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IQBiasControlUIFigure           matlab.ui.Figure
        ControlParametersPanel          matlab.ui.container.Panel
        DisableoutputonholdSwitch       matlab.ui.control.Switch
        DisableoutputonholdSwitchLabel  matlab.ui.control.Label
        HoldSwitch                      matlab.ui.control.Switch
        HoldSwitchLabel                 matlab.ui.control.Label
        UpperLim3EditField              matlab.ui.control.NumericEditField
        UpperLim3EditFieldLabel         matlab.ui.control.Label
        LowerLim3EditField              matlab.ui.control.NumericEditField
        LowerLim3EditFieldLabel         matlab.ui.control.Label
        UpperLim2EditField              matlab.ui.control.NumericEditField
        UpperLim2EditFieldLabel         matlab.ui.control.Label
        LowerLim2EditField              matlab.ui.control.NumericEditField
        LowerLim2EditFieldLabel         matlab.ui.control.Label
        UpperLim1EditField              matlab.ui.control.NumericEditField
        UpperLim1EditFieldLabel         matlab.ui.control.Label
        LowerLim1EditField              matlab.ui.control.NumericEditField
        LowerLim1EditFieldLabel         matlab.ui.control.Label
        Divisor3Spinner                 matlab.ui.control.Spinner
        Divisor3SpinnerLabel            matlab.ui.control.Label
        Divisor2Spinner                 matlab.ui.control.Spinner
        Divisor2SpinnerLabel            matlab.ui.control.Label
        Divisor1Spinner                 matlab.ui.control.Spinner
        Divisor1SpinnerLabel            matlab.ui.control.Label
        K33Spinner                      matlab.ui.control.Spinner
        K33SpinnerLabel                 matlab.ui.control.Label
        K32Spinner                      matlab.ui.control.Spinner
        K32SpinnerLabel                 matlab.ui.control.Label
        K31Spinner                      matlab.ui.control.Spinner
        K31SpinnerLabel                 matlab.ui.control.Label
        K23Spinner                      matlab.ui.control.Spinner
        K23SpinnerLabel                 matlab.ui.control.Label
        K22Spinner                      matlab.ui.control.Spinner
        K22SpinnerLabel                 matlab.ui.control.Label
        K21Spinner                      matlab.ui.control.Spinner
        K21SpinnerLabel                 matlab.ui.control.Label
        K13Spinner                      matlab.ui.control.Spinner
        K13SpinnerLabel                 matlab.ui.control.Label
        K12Spinner                      matlab.ui.control.Spinner
        K12SpinnerLabel                 matlab.ui.control.Label
        K11Spinner                      matlab.ui.control.Spinner
        K11SpinnerLabel                 matlab.ui.control.Label
        SetPointI2fEditField            matlab.ui.control.NumericEditField
        SetPointI2fEditFieldLabel       matlab.ui.control.Label
        SetPointQ1fEditField            matlab.ui.control.NumericEditField
        SetPointQ1fEditFieldLabel       matlab.ui.control.Label
        SetPointI1fEditField            matlab.ui.control.NumericEditField
        SetPointI1fEditFieldLabel       matlab.ui.control.Label
        EnableSwitch                    matlab.ui.control.Switch
        EnableSwitchLabel               matlab.ui.control.Label
        FIFOSettingsPanel               matlab.ui.container.Panel
        FIFO4CheckBox                   matlab.ui.control.CheckBox
        FIFO3CheckBox                   matlab.ui.control.CheckBox
        FIFO2CheckBox                   matlab.ui.control.CheckBox
        FIFO1CheckBox                   matlab.ui.control.CheckBox
        FIFO4DropDown                   matlab.ui.control.DropDown
        FIFO4DropDownLabel              matlab.ui.control.Label
        FIFO3DropDown                   matlab.ui.control.DropDown
        FIFO3DropDownLabel              matlab.ui.control.Label
        FIFO2DropDown                   matlab.ui.control.DropDown
        FIFO2DropDownLabel              matlab.ui.control.Label
        FIFO1DropDown                   matlab.ui.control.DropDown
        FIFO1DropDownLabel              matlab.ui.control.Label
        ManualControlPanel              matlab.ui.container.Panel
        CorrectPWMButton                matlab.ui.control.Button
        DCIncrementEditField            matlab.ui.control.NumericEditField
        DCIncrementEditFieldLabel       matlab.ui.control.Label
        DC3Spinner                      matlab.ui.control.Spinner
        DC3SpinnerLabel                 matlab.ui.control.Label
        DC3Slider                       matlab.ui.control.Slider
        SliderLabel_3                   matlab.ui.control.Label
        DC2Spinner                      matlab.ui.control.Spinner
        DC2SpinnerLabel                 matlab.ui.control.Label
        DC2Slider                       matlab.ui.control.Slider
        SliderLabel_2                   matlab.ui.control.Label
        DC1Spinner                      matlab.ui.control.Spinner
        DC1SpinnerLabel                 matlab.ui.control.Label
        DC1Slider                       matlab.ui.control.Slider
        SliderLabel                     matlab.ui.control.Label
        ApplicationSettingsPanel        matlab.ui.container.Panel
        YLim2EditField                  matlab.ui.control.EditField
        YLim2EditFieldLabel             matlab.ui.control.Label
        AcquisitionSamplesEditField     matlab.ui.control.NumericEditField
        AcquisitionSamplesEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        YLim1EditField                  matlab.ui.control.EditField
        YLim1Label                      matlab.ui.control.Label
        PlotUpdateTimemsEditField       matlab.ui.control.NumericEditField
        PlotUpdateTimemsEditFieldLabel  matlab.ui.control.Label
        FetchDataButton                 matlab.ui.control.Button
        AutoFetchDataSwitch             matlab.ui.control.Switch
        AutoFetchDataSwitchLabel        matlab.ui.control.Label
        FetchButton                     matlab.ui.control.Button
        UploadButton                    matlab.ui.control.Button
        AutoUpdateSwitch                matlab.ui.control.Switch
        AutoUpdateSwitchLabel           matlab.ui.control.Label
        AcquisitionSettingsPanel        matlab.ui.container.Panel
        PhaseCorrectiondegEditField     matlab.ui.control.NumericEditField
        PhaseCorrectiondegEditFieldLabel  matlab.ui.control.Label
        Log2ofCICshiftSpinner           matlab.ui.control.Spinner
        Log2ofCICshiftSpinnerLabel      matlab.ui.control.Label
        DemodPhase2degEditField         matlab.ui.control.NumericEditField
        DemodPhase2degEditFieldLabel    matlab.ui.control.Label
        DemodPhase1degEditField         matlab.ui.control.NumericEditField
        DemodPhase1degEditFieldLabel    matlab.ui.control.Label
        OutputScaleEditField            matlab.ui.control.NumericEditField
        OutputScaleEditFieldLabel       matlab.ui.control.Label
        ModulationFreqMHzEditField      matlab.ui.control.NumericEditField
        ModulationFreqMHzEditFieldLabel  matlab.ui.control.Label
        BWHzEditField                   matlab.ui.control.NumericEditField
        BWHzEditFieldLabel              matlab.ui.control.Label
        dtsEditField                    matlab.ui.control.NumericEditField
        dtsEditFieldLabel               matlab.ui.control.Label
        Log2CICRateSpinner              matlab.ui.control.Spinner
        Log2CICRateSpinnerLabel         matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        device                           IQBiasControl % IQBiasControl control
        autoUpdate % Description
        int_timer % Description
        plotType % Description
    end
    
    methods (Access = public)
        
        function results = updateDevice(app)
            if strcmpi(app.AutoUpdateSwitch.Value,'On') && strcmpi(app.AutoFetchDataSwitch.Value,'Off')
                app.device.upload;
            end
            app.updateDisplay;
        end
        
        function results = updateDisplay(app)
            %
            % Shorten names
            %
            d = app.device;
            c = d.control;
            %
            % Main settings
            %
            app.ModulationFreqMHzEditField.Value = d.phase_inc.value/1e6;
            app.PhaseCorrectiondegEditField.Value = d.phase_correction.value;
            app.OutputScaleEditField.Value = d.output_scale.value;
            app.DemodPhase1degEditField.Value = d.phase_offset.value;
            app.DemodPhase2degEditField.Value = d.dds2_phase_offset.value;
            app.Log2CICRateSpinner.Value = d.log2_rate.value;
            app.Log2ofCICshiftSpinner.Value = d.cic_shift.value;
            app.dtsEditField.Value = d.dt();
            app.BWHzEditField.Value = 1/(2*d.dt());
            %
            % PWM settings
            %
            app.DC1Spinner.Value = d.pwm(1).value;
            app.DC1Slider.Value = d.pwm(1).value;
            app.DC2Spinner.Value = d.pwm(2).value;
            app.DC2Slider.Value = d.pwm(2).value;
            app.DC3Spinner.Value = d.pwm(3).value;
            app.DC3Slider.Value = d.pwm(3).value;
            %
            % Control settings
            %
            app.EnableSwitch.Value = app.convert_on_off(c.enable.value);
            app.HoldSwitch.Value = app.convert_on_off(c.hold.value);
            app.DisableoutputonholdSwitch.Value = app.convert_on_off(d.disable_dac_on_hold.value);
            app.SetPointI1fEditField.Value = c.controls(1).value;
            app.SetPointQ1fEditField.Value = c.controls(2).value;
            app.SetPointI2fEditField.Value = c.controls(3).value;
            for row = 1:3
                for col = 1:3
                    s = sprintf('K%d%dSpinner',row,col);
                    app.(s).Value = c.gains(row,col).value;
                end
                s = sprintf('Divisor%dSpinner',row);
                app.(s).Value = c.divisors(row).value;
                s = sprintf('LowerLim%dEditField',row);
                app.(s).Value = c.lower_limits(row).value;
                s = sprintf('UpperLim%dEditField',row);
                app.(s).Value = c.upper_limits(row).value;
            end

            %
            % FIFO routing table
            %
            app.FIFO1DropDown.Value = app.convert_fifo_route(d.fifo_route(1).value);
            app.FIFO2DropDown.Value = app.convert_fifo_route(d.fifo_route(2).value);
            app.FIFO3DropDown.Value = app.convert_fifo_route(d.fifo_route(3).value);
            app.FIFO4DropDown.Value = app.convert_fifo_route(d.fifo_route(4).value);
        end
        
        function results = updatePlot(app)
            app.device.upload;
            app.device.getBiasData(app.AcquisitionSamplesEditField.Value);
            if numel(app.device.t) ~= size(app.device.data,1)
%                 fprintf('Data acquisition sizes are wrong\n');
                return;
            end
            yyaxis(app.UIAxes,'left');
            cla(app.UIAxes);
            yyaxis(app.UIAxes,'right');
            cla(app.UIAxes);
            clr = {'#0072BD','#D95319','#EDB120','#7E2F8E'};
            for nn = 1:size(app.device.data,2)
                s = sprintf('FIFO%dCheckBox',nn);
                s2 = sprintf('FIFO%dDropDown',nn);
                if app.(s).Value
                    if ~app.convert_fifo_route(app.(s2).Value)
                        yyaxis(app.UIAxes,'left');
                        plot(app.UIAxes,app.device.t,app.device.data(:,nn),'.-','color',clr{nn});
                        hold(app.UIAxes,'on');
                    else
                        yyaxis(app.UIAxes,'right');
                        plot(app.UIAxes,app.device.t,app.device.data(:,nn)*app.device.CONV_PWM,'.-','color',clr{nn});
                        hold(app.UIAxes,'on');
                    end
                end
            end
            yyaxis(app.UIAxes,'left');
            hold(app.UIAxes,'off');
            app.UIAxes.XLabel.String = 'Time [s]';
            app.UIAxes.YLabel.String = 'Signal [arb. units]';
            set(app.UIAxes,'XGrid','on','YGrid','on')
            lims1 = sscanf(app.YLim1EditField.Value,'%f,%f');
            if ~isempty(lims1)
                try
                    set(app.UIAxes,'YLim',sort(lims1));
                catch
                    
                end
            end
            
            yyaxis(app.UIAxes,'right');
            hold(app.UIAxes,'off');
            app.UIAxes.XLabel.String = 'Time [s]';
            app.UIAxes.YLabel.String = 'Signal [V]';
            set(app.UIAxes,'XGrid','on','YGrid','on')
            lims2 = sscanf(app.YLim2EditField.Value,'%f,%f');
            if ~isempty(lims2)
                try
                    set(app.UIAxes,'YLim',sort(lims2));
                catch
                    
                end
            end
        end
        
        
        function results = convert_fifo_route(app,v)
            if isnumeric(v)
                if v == 0
                    results = 'In';
                else
                    results = 'Out';
                end
            else
                if strcmpi(v,'In')
                    results = 0;
                elseif strcmpi(v,'Out')
                    results = 1;
                end
            end
        end

        function results = convert_on_off(app,v)
            if isnumeric(v)
                if v == 0
                    results = 'Off';
                else
                    results = 'On';
                end
            else
                if strcmpi(v,'Off')
                    results = 0;
                elseif strcmpi(v,'On')
                    results = 1;
                end
            end
        end
        
        function set_timer(app,value)
            if strcmpi(value,'off')
                if isa(app.int_timer,'timer')
                    stop(app.int_timer);
                    delete(app.int_timer);
                    app.int_timer = [];
                end
                app.AutoFetchDataSwitch.Value = 'Off';
            elseif strcmpi(value,'on')
                app.int_timer = timer('ExecutionMode','fixedSpacing','Name','Data fetch timer','Period',app.PlotUpdateTimemsEditField.Value/1e3);
                app.int_timer.ErrorFcn = @(obj,event) delete(obj);
                app.int_timer.TimerFcn = @(~,~) app.updatePlot;
                start(app.int_timer);
                app.AutoFetchDataSwitch.Value = 'On';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if nargin < 2
                app.device = IQBiasControl;
            elseif isa(varargin{1},'IQBiasControl')
                app.device = varargin{1};
            elseif ischar(varargin{1}) || isstring(varargin{1})
                if numel(varargin) == 1
                    app.device = IQBiasControl(varargin{1});
                else
                    app.device = IQBiasControl(varargin{1},varargin{2});
                end
                app.device.setDefaults;
            else
                error('Input arguments must be either a ''IQBiasControl'' object or an IP address and port number!');
            end
            
            if nargin == 2 && ~isa(varargin{1},'Device')
                try
                    app.device.conn.open;
                    app.device.fetch;
                catch ME
                    warning('A connection to the socket server could not be established!');
                end
            end
            app.updateDisplay;
            warning('off','MATLAB:callback:error');
            
        end

        % Value changed function: Log2CICRateSpinner
        function Log2CICRateSpinnerValueChanged(app, event)
            value = app.Log2CICRateSpinner.Value;
            app.device.log2_rate.set(value);
            app.updateDevice;
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
           app.device.control.enable.set(app.convert_on_off(value));
           app.updateDevice;
        end

        % Value changed function: LowerLim1EditField
        function LowerLim1EditFieldValueChanged(app, event)
            value = app.LowerLim1EditField.Value;
            app.device.control.lower_limits(1).set(value);
            app.updateDevice;
        end

        % Value changed function: AutoUpdateSwitch
        function AutoUpdateSwitchValueChanged(app, event)
            value = app.AutoUpdateSwitch.Value;
            if strcmpi(value,'off')
                app.autoUpdate = 0;
            else
                app.autoUpdate = 1;
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.device.upload;
        end

        % Button pushed function: FetchButton
        function FetchButtonPushed(app, event)
            app.device.fetch;
            app.updateDisplay;
        end

        % Button pushed function: FetchDataButton
        function FetchDataButtonPushed(app, event)
            app.updatePlot;
        end

        % Value changed function: AutoFetchDataSwitch
        function AutoFetchDataSwitchValueChanged(app, event)
            value = app.AutoFetchDataSwitch.Value;
            app.set_timer(value);
        end

        % Value changed function: Divisor1Spinner
        function Divisor1SpinnerValueChanged(app, event)
            value = app.Divisor1Spinner.Value;
            app.device.control.divisors(1).set(value);
            app.updateDevice;
        end

        % Value changed function: PlotUpdateTimemsEditField
        function PlotUpdateTimemsEditFieldValueChanged(app, event)
            value = app.PlotUpdateTimemsEditField.Value;
            if isa(app.int_timer,'timer') && isvalid(app.int_timer)
                stop(app.int_timer);
                app.int_timer.Period = value/1e3;
                start(app.int_timer);
            end
        end

        % Value changed function: ModulationFreqMHzEditField
        function ModulationFreqMHzEditFieldValueChanged(app, event)
            value = app.ModulationFreqMHzEditField.Value;
            app.device.phase_inc.set(value*1e6);
            app.updateDevice;
        end

        % Value changed function: DemodPhase1degEditField
        function DemodPhase1degEditFieldValueChanged(app, event)
            value = app.DemodPhase1degEditField.Value;
            app.device.phase_offset.set(value);
            app.updateDevice;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile;
            data = load([path,file],'saved_device');
            app.device.loadstruct(data.saved_device);
            app.updateDevice;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saved_device = app.device.struct;
            uisave('saved_device','servo-parameters');
        end

        % Value changed function: OutputScaleEditField
        function OutputScaleEditFieldValueChanged(app, event)
            value = app.OutputScaleEditField.Value;
            app.device.output_scale.set(value);
            app.updateDevice;
        end

        % Value changed function: Log2ofCICshiftSpinner
        function Log2ofCICshiftSpinnerValueChanged(app, event)
            value = app.Log2ofCICshiftSpinner.Value;
            app.device.cic_shift.set(value);
            app.updateDevice;
        end

        % Value changed function: DemodPhase2degEditField
        function DemodPhase2degEditFieldValueChanged(app, event)
            value = app.DemodPhase2degEditField.Value;
            app.device.dds2_phase_offset.set(value);
            app.updateDevice;
        end

        % Value changed function: DC1Spinner
        function DC1SpinnerValueChanged(app, event)
            value = app.DC1Spinner.Value;
            app.device.pwm(1).set(value);
            app.updateDevice;
        end

        % Value changed function: DC1Slider
        function DC1SliderValueChanged(app, event)
            value = app.DC1Slider.Value;
            app.device.pwm(1).set(value);
            app.updateDevice;
        end

        % Value changed function: DC2Spinner
        function DC2SpinnerValueChanged(app, event)
            value = app.DC2Spinner.Value;
            app.device.pwm(2).set(value);
            app.updateDevice;
        end

        % Value changed function: DC2Slider
        function DC2SliderValueChanged(app, event)
            value = app.DC2Slider.Value;
            app.device.pwm(2).set(value);
            app.updateDevice;
        end

        % Value changed function: DC3Spinner
        function DC3SpinnerValueChanged(app, event)
            value = app.DC3Spinner.Value;
            app.device.pwm(3).set(value);
            app.updateDevice;
        end

        % Value changed function: DC3Slider
        function DC3SliderValueChanged(app, event)
            value = app.DC3Slider.Value;
            app.device.pwm(3).set(value);
            app.updateDevice;
        end

        % Value changed function: DCIncrementEditField
        function DCIncrementEditFieldValueChanged(app, event)
            value = app.DCIncrementEditField.Value;
            value = app.device.CONV_PWM*ceil(value/app.device.CONV_PWM);
            app.DCIncrementEditField.Value = value;
            app.DC1Spinner.Step = value;
            app.DC2Spinner.Step = value;
            app.DC3Spinner.Step = value;
        end

        % Value changed function: FIFO1DropDown
        function FIFO1DropDownValueChanged(app, event)
            value = app.FIFO1DropDown.Value;
            app.device.fifo_route(1).set(app.convert_fifo_route(value));
            app.updateDevice;
        end

        % Value changed function: FIFO2DropDown
        function FIFO2DropDownValueChanged(app, event)
            value = app.FIFO2DropDown.Value;
            app.device.fifo_route(2).set(app.convert_fifo_route(value));
            app.updateDevice;
        end

        % Value changed function: FIFO3DropDown
        function FIFO3DropDownValueChanged(app, event)
            value = app.FIFO3DropDown.Value;
            app.device.fifo_route(3).set(app.convert_fifo_route(value));
            app.updateDevice;
        end

        % Value changed function: FIFO4DropDown
        function FIFO4DropDownValueChanged(app, event)
            value = app.FIFO4DropDown.Value;
            app.device.fifo_route(4).set(app.convert_fifo_route(value));
            app.updateDevice;
        end

        % Value changed function: HoldSwitch
        function HoldSwitchValueChanged(app, event)
            value = app.HoldSwitch.Value;
            app.device.control.hold.set(app.convert_on_off(value));
            app.updateDevice;
        end

        % Value changed function: SetPointI1fEditField
        function SetPointI1fEditFieldValueChanged(app, event)
            value = app.SetPointI1fEditField.Value;
            app.device.control.controls(1).set(value);
            app.updateDevice;
        end

        % Value changed function: SetPointQ1fEditField
        function SetPointQ1fEditFieldValueChanged(app, event)
            value = app.SetPointQ1fEditField.Value;
            app.device.control.controls(2).set(value);
            app.updateDevice;
        end

        % Value changed function: SetPointI2fEditField
        function SetPointI2fEditFieldValueChanged(app, event)
            value = app.SetPointI2fEditField.Value;
            app.device.control.controls(3).set(value);
            app.updateDevice;
        end

        % Value changed function: K11Spinner
        function K11SpinnerValueChanged(app, event)
            value = app.K11Spinner.Value;
            app.device.control.gains(1,1).set(value);
            app.updateDevice;
        end

        % Value changed function: K12Spinner
        function K12SpinnerValueChanged(app, event)
            value = app.K12Spinner.Value;
            app.device.control.gains(1,2).set(value);
            app.updateDevice;
        end

        % Value changed function: K13Spinner
        function K13SpinnerValueChanged(app, event)
            value = app.K13Spinner.Value;
            app.device.control.gains(1,3).set(value);
            app.updateDevice;
        end

        % Value changed function: K21Spinner
        function K21SpinnerValueChanged(app, event)
            value = app.K21Spinner.Value;
            app.device.control.gains(2,1).set(value);
            app.updateDevice;
        end

        % Value changed function: K22Spinner
        function K22SpinnerValueChanged(app, event)
            value = app.K22Spinner.Value;
            app.device.control.gains(2,2).set(value);
            app.updateDevice;
        end

        % Value changed function: K23Spinner
        function K23SpinnerValueChanged(app, event)
            value = app.K23Spinner.Value;
            app.device.control.gains(2,3).set(value);
            app.updateDevice;
        end

        % Value changed function: K31Spinner
        function K31SpinnerValueChanged(app, event)
            value = app.K31Spinner.Value;
            app.device.control.gains(3,1).set(value);
            app.updateDevice;
        end

        % Value changed function: K32Spinner
        function K32SpinnerValueChanged(app, event)
            value = app.K32Spinner.Value;
            app.device.control.gains(3,2).set(value);
            app.updateDevice;
        end

        % Value changed function: K33Spinner
        function K33SpinnerValueChanged(app, event)
            value = app.K33Spinner.Value;
            app.device.control.gains(3,3).set(value);
            app.updateDevice;
        end

        % Value changed function: Divisor2Spinner
        function Divisor2SpinnerValueChanged(app, event)
            value = app.Divisor2Spinner.Value;
            app.device.control.divisors(2).set(value);
            app.updateDevice;
        end

        % Value changed function: Divisor3Spinner
        function Divisor3SpinnerValueChanged(app, event)
            value = app.Divisor3Spinner.Value;
            app.device.control.divisors(3).set(value);
            app.updateDevice;
        end

        % Value changed function: LowerLim2EditField
        function LowerLim2EditFieldValueChanged(app, event)
            value = app.LowerLim2EditField.Value;
            app.device.control.lower_limits(2).set(value);
            app.updateDevice;
        end

        % Value changed function: LowerLim3EditField
        function LowerLim3EditFieldValueChanged(app, event)
            value = app.LowerLim3EditField.Value;
            app.device.control.lower_limits(3).set(value);
            app.updateDevice;
        end

        % Value changed function: UpperLim1EditField
        function UpperLim1EditFieldValueChanged(app, event)
            value = app.UpperLim1EditField.Value;
            app.device.control.upper_limits(1).set(value);
            app.updateDevice;
        end

        % Value changed function: UpperLim2EditField
        function UpperLim2EditFieldValueChanged(app, event)
            value = app.UpperLim2EditField.Value;
            app.device.control.upper_limits(2).set(value);
            app.updateDevice;
        end

        % Value changed function: UpperLim3EditField
        function UpperLim3EditFieldValueChanged(app, event)
            value = app.UpperLim3EditField.Value;
            app.device.control.upper_limits(3).set(value);
            app.updateDevice;
        end

        % Value changed function: PhaseCorrectiondegEditField
        function PhaseCorrectiondegEditFieldValueChanged(app, event)
            value = app.PhaseCorrectiondegEditField.Value;
            app.device.phase_correction.set(value);
            app.updateDevice;
        end

        % Button pushed function: CorrectPWMButton
        function CorrectPWMButtonPushed(app, event)
            if app.device.control.enable.value
                if strcmpi(app.AutoFetchDataSwitch.Value,'On')
                    app.set_timer('off');
                    app.device.correct_pwm;
                    pause(0.1);
                    app.set_timer('on');
                else
                    app.device.correct_pwm;
                end
                app.updateDisplay;
            end
        end

        % Value changed function: DisableoutputonholdSwitch
        function DisableoutputonholdSwitchValueChanged(app, event)
            value = app.DisableoutputonholdSwitch.Value;
            app.device.disable_dac_on_hold.set(app.convert_on_off(value));
            app.updateDevice;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IQBiasControlUIFigure = uifigure('Visible', 'off');
            app.IQBiasControlUIFigure.Name = 'IQ Bias Control';
            app.UIAxes = uiaxes(app.IQBiasControlUIFigure);
            title(app.UIAxes, 'Measurements')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AcquisitionSettingsPanel = uipanel(app.IQBiasControlUIFigure);
            app.AcquisitionSettingsPanel.Title = 'Acquisition Settings';
            app.Log2CICRateSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.Log2CICRateSpinnerLabel.Text = 'Log2(CIC Rate)';
            app.Log2CICRateSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.Log2CICRateSpinner.Limits = [8 13];
            app.Log2CICRateSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2CICRateSpinnerValueChanged, true);
            app.Log2CICRateSpinner.Value = 10;
            app.dtsEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.dtsEditFieldLabel.Text = 'dt [s]';
            app.dtsEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.dtsEditField.ValueDisplayFormat = '%.2e';
            app.dtsEditField.Editable = 'off';
            app.BWHzEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.BWHzEditFieldLabel.Text = 'BW [Hz]';
            app.BWHzEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.BWHzEditField.ValueDisplayFormat = '%.2e';
            app.BWHzEditField.Editable = 'off';
            app.ModulationFreqMHzEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.ModulationFreqMHzEditFieldLabel.Text = 'Modulation Freq [MHz]';
            app.ModulationFreqMHzEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.ModulationFreqMHzEditField.Limits = [0 30];
            app.ModulationFreqMHzEditField.ValueDisplayFormat = '%.2f';
            app.ModulationFreqMHzEditField.ValueChangedFcn = createCallbackFcn(app, @ModulationFreqMHzEditFieldValueChanged, true);
            app.ModulationFreqMHzEditField.Value = 1;
            app.OutputScaleEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.OutputScaleEditFieldLabel.Text = 'Output Scale';
            app.OutputScaleEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.OutputScaleEditField.Limits = [0 1];
            app.OutputScaleEditField.ValueDisplayFormat = '%.3f';
            app.OutputScaleEditField.ValueChangedFcn = createCallbackFcn(app, @OutputScaleEditFieldValueChanged, true);
            app.OutputScaleEditField.Value = 1;
            app.DemodPhase1degEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DemodPhase1degEditFieldLabel.Text = 'Demod. Phase 1 [deg]';
            app.DemodPhase1degEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.DemodPhase1degEditField.ValueDisplayFormat = '%.1f';
            app.DemodPhase1degEditField.ValueChangedFcn = createCallbackFcn(app, @DemodPhase1degEditFieldValueChanged, true);
            app.DemodPhase2degEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.DemodPhase2degEditFieldLabel.Text = 'Demod. Phase 2 [deg]';
            app.DemodPhase2degEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.DemodPhase2degEditField.ValueDisplayFormat = '%.1f';
            app.DemodPhase2degEditField.ValueChangedFcn = createCallbackFcn(app, @DemodPhase2degEditFieldValueChanged, true);
            app.Log2ofCICshiftSpinnerLabel = uilabel(app.AcquisitionSettingsPanel);
            app.Log2ofCICshiftSpinnerLabel.Text = 'Log2 of CIC shift';
            app.Log2ofCICshiftSpinner = uispinner(app.AcquisitionSettingsPanel);
            app.Log2ofCICshiftSpinner.Limits = [-100 100];
            app.Log2ofCICshiftSpinner.ValueChangedFcn = createCallbackFcn(app, @Log2ofCICshiftSpinnerValueChanged, true);
            app.PhaseCorrectiondegEditFieldLabel = uilabel(app.AcquisitionSettingsPanel);
            app.PhaseCorrectiondegEditFieldLabel.Text = 'Phase Correction [deg]';
            app.PhaseCorrectiondegEditField = uieditfield(app.AcquisitionSettingsPanel, 'numeric');
            app.PhaseCorrectiondegEditField.ValueDisplayFormat = '%.1f';
            app.PhaseCorrectiondegEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseCorrectiondegEditFieldValueChanged, true);
            app.ApplicationSettingsPanel = uipanel(app.IQBiasControlUIFigure);
            app.ApplicationSettingsPanel.Title = 'Application Settings';
            app.AutoUpdateSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.AutoUpdateSwitchLabel.Text = 'Auto-Update';
            app.AutoUpdateSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.AutoUpdateSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoUpdateSwitchValueChanged, true);
            app.UploadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.FetchButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchButton.ButtonPushedFcn = createCallbackFcn(app, @FetchButtonPushed, true);
            app.FetchButton.Text = 'Fetch';
            app.AutoFetchDataSwitchLabel = uilabel(app.ApplicationSettingsPanel);
            app.AutoFetchDataSwitchLabel.Text = 'Auto-Fetch Data';
            app.AutoFetchDataSwitch = uiswitch(app.ApplicationSettingsPanel, 'slider');
            app.AutoFetchDataSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoFetchDataSwitchValueChanged, true);
            app.FetchDataButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.FetchDataButton.ButtonPushedFcn = createCallbackFcn(app, @FetchDataButtonPushed, true);
            app.FetchDataButton.Text = 'Fetch Data';
            app.PlotUpdateTimemsEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.PlotUpdateTimemsEditFieldLabel.Text = 'Plot Update Time [ms]';
            app.PlotUpdateTimemsEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.PlotUpdateTimemsEditField.Limits = [0 Inf];
            app.PlotUpdateTimemsEditField.ValueDisplayFormat = '%4.2g';
            app.PlotUpdateTimemsEditField.ValueChangedFcn = createCallbackFcn(app, @PlotUpdateTimemsEditFieldValueChanged, true);
            app.PlotUpdateTimemsEditField.Value = 10;
            app.YLim1Label = uilabel(app.ApplicationSettingsPanel);
            app.YLim1Label.Text = 'YLim1';
            app.YLim1EditField = uieditfield(app.ApplicationSettingsPanel, 'text');
            app.YLim1EditField.Value = '-30e3,30e3';
            app.LoadButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ApplicationSettingsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AcquisitionSamplesEditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.AcquisitionSamplesEditFieldLabel.Text = 'Acquisition Samples';
            app.AcquisitionSamplesEditField = uieditfield(app.ApplicationSettingsPanel, 'numeric');
            app.AcquisitionSamplesEditField.Limits = [0 Inf];
            app.AcquisitionSamplesEditField.ValueDisplayFormat = '%.0f';
            app.AcquisitionSamplesEditField.Value = 1000;
            app.YLim2EditFieldLabel = uilabel(app.ApplicationSettingsPanel);
            app.YLim2EditFieldLabel.Text = 'YLim2';
            app.YLim2EditField = uieditfield(app.ApplicationSettingsPanel, 'text');
            app.YLim2EditField.Value = '0,1';
            app.ManualControlPanel = uipanel(app.IQBiasControlUIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.SliderLabel = uilabel(app.ManualControlPanel);
            app.SliderLabel.Visible = 'off';
            app.SliderLabel.Text = 'Slider';
            app.DC1Slider = uislider(app.ManualControlPanel);
            app.DC1Slider.Limits = [0 1.5];
            app.DC1Slider.ValueChangedFcn = createCallbackFcn(app, @DC1SliderValueChanged, true);
            app.DC1SpinnerLabel = uilabel(app.ManualControlPanel);
            app.DC1SpinnerLabel.Text = 'DC1';
            app.DC1Spinner = uispinner(app.ManualControlPanel);
            app.DC1Spinner.Step = 0.01;
            app.DC1Spinner.Limits = [0 1.5];
            app.DC1Spinner.ValueChangedFcn = createCallbackFcn(app, @DC1SpinnerValueChanged, true);
            app.SliderLabel_2 = uilabel(app.ManualControlPanel);
            app.SliderLabel_2.Visible = 'off';
            app.SliderLabel_2.Text = 'Slider';
            app.DC2Slider = uislider(app.ManualControlPanel);
            app.DC2Slider.Limits = [0 1.5];
            app.DC2Slider.ValueChangedFcn = createCallbackFcn(app, @DC2SliderValueChanged, true);
            app.DC2SpinnerLabel = uilabel(app.ManualControlPanel);
            app.DC2SpinnerLabel.Text = 'DC2';
            app.DC2Spinner = uispinner(app.ManualControlPanel);
            app.DC2Spinner.Step = 0.01;
            app.DC2Spinner.Limits = [0 1.5];
            app.DC2Spinner.ValueChangedFcn = createCallbackFcn(app, @DC2SpinnerValueChanged, true);
            app.SliderLabel_3 = uilabel(app.ManualControlPanel);
            app.SliderLabel_3.Visible = 'off';
            app.SliderLabel_3.Text = 'Slider';
            app.DC3Slider = uislider(app.ManualControlPanel);
            app.DC3Slider.Limits = [0 1.5];
            app.DC3Slider.ValueChangedFcn = createCallbackFcn(app, @DC3SliderValueChanged, true);
            app.DC3SpinnerLabel = uilabel(app.ManualControlPanel);
            app.DC3SpinnerLabel.Text = 'DC3';
            app.DC3Spinner = uispinner(app.ManualControlPanel);
            app.DC3Spinner.Step = 0.01;
            app.DC3Spinner.Limits = [0 1.5];
            app.DC3Spinner.ValueChangedFcn = createCallbackFcn(app, @DC3SpinnerValueChanged, true);
            app.DCIncrementEditFieldLabel = uilabel(app.ManualControlPanel);
            app.DCIncrementEditFieldLabel.Text = 'DC Increment';
            app.DCIncrementEditField = uieditfield(app.ManualControlPanel, 'numeric');
            app.DCIncrementEditField.Limits = [0 1];
            app.DCIncrementEditField.ValueDisplayFormat = '%.3f';
            app.DCIncrementEditField.ValueChangedFcn = createCallbackFcn(app, @DCIncrementEditFieldValueChanged, true);
            app.DCIncrementEditField.Value = 0.01;
            app.CorrectPWMButton = uibutton(app.ManualControlPanel, 'push');
            app.CorrectPWMButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectPWMButtonPushed, true);
            app.CorrectPWMButton.Text = 'Correct PWM';
            app.FIFOSettingsPanel = uipanel(app.IQBiasControlUIFigure);
            app.FIFOSettingsPanel.Title = 'FIFO Settings';
            app.FIFO1DropDownLabel = uilabel(app.FIFOSettingsPanel);
            app.FIFO1DropDownLabel.Text = 'FIFO 1';
            app.FIFO1DropDown = uidropdown(app.FIFOSettingsPanel);
            app.FIFO1DropDown.Items = {'In', 'Out'};
            app.FIFO1DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO1DropDownValueChanged, true);
            app.FIFO1DropDown.Value = 'In';
            app.FIFO2DropDownLabel = uilabel(app.FIFOSettingsPanel);
            app.FIFO2DropDownLabel.Text = 'FIFO 2';
            app.FIFO2DropDown = uidropdown(app.FIFOSettingsPanel);
            app.FIFO2DropDown.Items = {'In', 'Out'};
            app.FIFO2DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO2DropDownValueChanged, true);
            app.FIFO2DropDown.Value = 'In';
            app.FIFO3DropDownLabel = uilabel(app.FIFOSettingsPanel);
            app.FIFO3DropDownLabel.Text = 'FIFO 3';
            app.FIFO3DropDown = uidropdown(app.FIFOSettingsPanel);
            app.FIFO3DropDown.Items = {'In', 'Out'};
            app.FIFO3DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO3DropDownValueChanged, true);
            app.FIFO3DropDown.Value = 'In';
            app.FIFO4DropDownLabel = uilabel(app.FIFOSettingsPanel);
            app.FIFO4DropDownLabel.Text = 'FIFO 4';
            app.FIFO4DropDown = uidropdown(app.FIFOSettingsPanel);
            app.FIFO4DropDown.Items = {'In', 'Out'};
            app.FIFO4DropDown.ValueChangedFcn = createCallbackFcn(app, @FIFO4DropDownValueChanged, true);
            app.FIFO4DropDown.Value = 'In';
            app.FIFO1CheckBox = uicheckbox(app.FIFOSettingsPanel);
            app.FIFO1CheckBox.Text = 'FIFO1';
            app.FIFO1CheckBox.Value = true;
            app.FIFO2CheckBox = uicheckbox(app.FIFOSettingsPanel);
            app.FIFO2CheckBox.Text = 'FIFO2';
            app.FIFO2CheckBox.Value = true;
            app.FIFO3CheckBox = uicheckbox(app.FIFOSettingsPanel);
            app.FIFO3CheckBox.Text = 'FIFO3';
            app.FIFO3CheckBox.Value = true;
            app.FIFO4CheckBox = uicheckbox(app.FIFOSettingsPanel);
            app.FIFO4CheckBox.Text = 'FIFO4';
            app.FIFO4CheckBox.Value = true;
            app.ControlParametersPanel = uipanel(app.IQBiasControlUIFigure);
            app.ControlParametersPanel.Title = 'Control Parameters';
            app.EnableSwitchLabel = uilabel(app.ControlParametersPanel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.ControlParametersPanel, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.SetPointI1fEditFieldLabel = uilabel(app.ControlParametersPanel);
            app.SetPointI1fEditFieldLabel.Text = 'Set Point I 1f';
            app.SetPointI1fEditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.SetPointI1fEditField.Limits = [-10000 10000];
            app.SetPointI1fEditField.ValueDisplayFormat = '%4.4g';
            app.SetPointI1fEditField.ValueChangedFcn = createCallbackFcn(app, @SetPointI1fEditFieldValueChanged, true);
            app.SetPointQ1fEditFieldLabel = uilabel(app.ControlParametersPanel);
            app.SetPointQ1fEditFieldLabel.Text = 'Set Point Q 1f';
            app.SetPointQ1fEditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.SetPointQ1fEditField.Limits = [-10000 10000];
            app.SetPointQ1fEditField.ValueDisplayFormat = '%4.4g';
            app.SetPointQ1fEditField.ValueChangedFcn = createCallbackFcn(app, @SetPointQ1fEditFieldValueChanged, true);
            app.SetPointI2fEditFieldLabel = uilabel(app.ControlParametersPanel);
            app.SetPointI2fEditFieldLabel.Text = 'Set Point I 2f';
            app.SetPointI2fEditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.SetPointI2fEditField.Limits = [-10000 10000];
            app.SetPointI2fEditField.ValueDisplayFormat = '%4.4g';
            app.SetPointI2fEditField.ValueChangedFcn = createCallbackFcn(app, @SetPointI2fEditFieldValueChanged, true);
            app.K11SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K11SpinnerLabel.Text = 'K11';
            app.K11Spinner = uispinner(app.ControlParametersPanel);
            app.K11Spinner.Limits = [-128 127];
            app.K11Spinner.RoundFractionalValues = 'on';
            app.K11Spinner.ValueDisplayFormat = '%d';
            app.K11Spinner.ValueChangedFcn = createCallbackFcn(app, @K11SpinnerValueChanged, true);
            app.K12SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K12SpinnerLabel.Text = 'K12';
            app.K12Spinner = uispinner(app.ControlParametersPanel);
            app.K12Spinner.Limits = [-128 127];
            app.K12Spinner.RoundFractionalValues = 'on';
            app.K12Spinner.ValueDisplayFormat = '%d';
            app.K12Spinner.ValueChangedFcn = createCallbackFcn(app, @K12SpinnerValueChanged, true);
            app.K13SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K13SpinnerLabel.Text = 'K13';
            app.K13Spinner = uispinner(app.ControlParametersPanel);
            app.K13Spinner.Limits = [-128 127];
            app.K13Spinner.RoundFractionalValues = 'on';
            app.K13Spinner.ValueDisplayFormat = '%d';
            app.K13Spinner.ValueChangedFcn = createCallbackFcn(app, @K13SpinnerValueChanged, true);
            app.K21SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K21SpinnerLabel.Text = 'K21';
            app.K21Spinner = uispinner(app.ControlParametersPanel);
            app.K21Spinner.Limits = [-128 127];
            app.K21Spinner.RoundFractionalValues = 'on';
            app.K21Spinner.ValueDisplayFormat = '%d';
            app.K21Spinner.ValueChangedFcn = createCallbackFcn(app, @K21SpinnerValueChanged, true);
            app.K22SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K22SpinnerLabel.Text = 'K22';
            app.K22Spinner = uispinner(app.ControlParametersPanel);
            app.K22Spinner.Limits = [-128 127];
            app.K22Spinner.RoundFractionalValues = 'on';
            app.K22Spinner.ValueDisplayFormat = '%d';
            app.K22Spinner.ValueChangedFcn = createCallbackFcn(app, @K22SpinnerValueChanged, true);
            app.K23SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K23SpinnerLabel.Text = 'K23';
            app.K23Spinner = uispinner(app.ControlParametersPanel);
            app.K23Spinner.Limits = [-128 127];
            app.K23Spinner.RoundFractionalValues = 'on';
            app.K23Spinner.ValueDisplayFormat = '%d';
            app.K23Spinner.ValueChangedFcn = createCallbackFcn(app, @K23SpinnerValueChanged, true);
            app.K31SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K31SpinnerLabel.Text = 'K31';
            app.K31Spinner = uispinner(app.ControlParametersPanel);
            app.K31Spinner.Limits = [-128 127];
            app.K31Spinner.RoundFractionalValues = 'on';
            app.K31Spinner.ValueDisplayFormat = '%d';
            app.K31Spinner.ValueChangedFcn = createCallbackFcn(app, @K31SpinnerValueChanged, true);
            app.K32SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K32SpinnerLabel.Text = 'K32';
            app.K32Spinner = uispinner(app.ControlParametersPanel);
            app.K32Spinner.Limits = [-128 127];
            app.K32Spinner.RoundFractionalValues = 'on';
            app.K32Spinner.ValueDisplayFormat = '%d';
            app.K32Spinner.ValueChangedFcn = createCallbackFcn(app, @K32SpinnerValueChanged, true);
            app.K33SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.K33SpinnerLabel.Text = 'K33';
            app.K33Spinner = uispinner(app.ControlParametersPanel);
            app.K33Spinner.Limits = [-128 127];
            app.K33Spinner.RoundFractionalValues = 'on';
            app.K33Spinner.ValueDisplayFormat = '%d';
            app.K33Spinner.ValueChangedFcn = createCallbackFcn(app, @K33SpinnerValueChanged, true);
            app.Divisor1SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.Divisor1SpinnerLabel.Text = 'Divisor 1';
            app.Divisor1Spinner = uispinner(app.ControlParametersPanel);
            app.Divisor1Spinner.Limits = [0 255];
            app.Divisor1Spinner.RoundFractionalValues = 'on';
            app.Divisor1Spinner.ValueDisplayFormat = '%d';
            app.Divisor1Spinner.ValueChangedFcn = createCallbackFcn(app, @Divisor1SpinnerValueChanged, true);
            app.Divisor2SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.Divisor2SpinnerLabel.Text = 'Divisor 2';
            app.Divisor2Spinner = uispinner(app.ControlParametersPanel);
            app.Divisor2Spinner.Limits = [0 255];
            app.Divisor2Spinner.RoundFractionalValues = 'on';
            app.Divisor2Spinner.ValueDisplayFormat = '%d';
            app.Divisor2Spinner.ValueChangedFcn = createCallbackFcn(app, @Divisor2SpinnerValueChanged, true);
            app.Divisor3SpinnerLabel = uilabel(app.ControlParametersPanel);
            app.Divisor3SpinnerLabel.Text = 'Divisor 3';
            app.Divisor3Spinner = uispinner(app.ControlParametersPanel);
            app.Divisor3Spinner.Limits = [0 255];
            app.Divisor3Spinner.RoundFractionalValues = 'on';
            app.Divisor3Spinner.ValueDisplayFormat = '%d';
            app.Divisor3Spinner.ValueChangedFcn = createCallbackFcn(app, @Divisor3SpinnerValueChanged, true);
            app.LowerLim1EditFieldLabel = uilabel(app.ControlParametersPanel);
            app.LowerLim1EditFieldLabel.Text = 'Lower Lim. 1';
            app.LowerLim1EditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.LowerLim1EditField.Limits = [0 1];
            app.LowerLim1EditField.ValueDisplayFormat = '%4.4g';
            app.LowerLim1EditField.ValueChangedFcn = createCallbackFcn(app, @LowerLim1EditFieldValueChanged, true);
            app.UpperLim1EditFieldLabel = uilabel(app.ControlParametersPanel);
            app.UpperLim1EditFieldLabel.Text = 'Upper Lim. 1';
            app.UpperLim1EditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.UpperLim1EditField.Limits = [0 1.6];
            app.UpperLim1EditField.ValueDisplayFormat = '%4.4g';
            app.UpperLim1EditField.ValueChangedFcn = createCallbackFcn(app, @UpperLim1EditFieldValueChanged, true);
            app.UpperLim1EditField.Value = 1;
            app.LowerLim2EditFieldLabel = uilabel(app.ControlParametersPanel);
            app.LowerLim2EditFieldLabel.Text = 'Lower Lim. 2';
            app.LowerLim2EditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.LowerLim2EditField.Limits = [0 1];
            app.LowerLim2EditField.ValueDisplayFormat = '%4.4g';
            app.LowerLim2EditField.ValueChangedFcn = createCallbackFcn(app, @LowerLim2EditFieldValueChanged, true);
            app.UpperLim2EditFieldLabel = uilabel(app.ControlParametersPanel);
            app.UpperLim2EditFieldLabel.Text = 'Upper Lim. 2';
            app.UpperLim2EditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.UpperLim2EditField.Limits = [0 1.6];
            app.UpperLim2EditField.ValueDisplayFormat = '%4.4g';
            app.UpperLim2EditField.ValueChangedFcn = createCallbackFcn(app, @UpperLim2EditFieldValueChanged, true);
            app.UpperLim2EditField.Value = 1;
            app.LowerLim3EditFieldLabel = uilabel(app.ControlParametersPanel);
            app.LowerLim3EditFieldLabel.Text = 'Lower Lim. 3';
            app.LowerLim3EditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.LowerLim3EditField.Limits = [0 1];
            app.LowerLim3EditField.ValueDisplayFormat = '%4.4g';
            app.LowerLim3EditField.ValueChangedFcn = createCallbackFcn(app, @LowerLim3EditFieldValueChanged, true);
            app.UpperLim3EditFieldLabel = uilabel(app.ControlParametersPanel);
            app.UpperLim3EditFieldLabel.Text = 'Upper Lim. 3';
            app.UpperLim3EditField = uieditfield(app.ControlParametersPanel, 'numeric');
            app.UpperLim3EditField.Limits = [0 1.6];
            app.UpperLim3EditField.ValueDisplayFormat = '%4.4g';
            app.UpperLim3EditField.ValueChangedFcn = createCallbackFcn(app, @UpperLim3EditFieldValueChanged, true);
            app.UpperLim3EditField.Value = 1;
            app.HoldSwitchLabel = uilabel(app.ControlParametersPanel);
            app.HoldSwitchLabel.Text = 'Hold';
            app.HoldSwitch = uiswitch(app.ControlParametersPanel, 'slider');
            app.HoldSwitch.ValueChangedFcn = createCallbackFcn(app, @HoldSwitchValueChanged, true);
            app.DisableoutputonholdSwitchLabel = uilabel(app.ControlParametersPanel);
            app.DisableoutputonholdSwitchLabel.Text = 'Disable output on hold?';
            app.DisableoutputonholdSwitch = uiswitch(app.ControlParametersPanel, 'slider');
            app.DisableoutputonholdSwitch.ValueChangedFcn = createCallbackFcn(app, @DisableoutputonholdSwitchValueChanged, true);
            % Show the figure after all components are created
            app.IQBiasControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IQ_Bias_Control_GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IQBiasControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IQBiasControlUIFigure)
        end
    end
end